classdef APP_RN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ConfiguracaoRedePanel          matlab.ui.container.Panel
        GuardarRNButton                matlab.ui.control.Button
        CarregarRNButton               matlab.ui.control.Button
        TreinarRNButton                matlab.ui.control.Button
        AplicarRNButton                matlab.ui.control.Button
        DivisoparaTreinoTesteValidaoPanel  matlab.ui.container.Panel
        TesteSpinnerLabel              matlab.ui.control.Label
        TesteSpinner                   matlab.ui.control.Spinner
        ValidacaoSpinnerLabel          matlab.ui.control.Label
        ValidacaoSpinner               matlab.ui.control.Spinner
        TreinoSpinnerLabel             matlab.ui.control.Label
        TreinoSpinner                  matlab.ui.control.Spinner
        ImagensPanel                   matlab.ui.container.Panel
        Pasta1CheckBox                 matlab.ui.control.CheckBox
        Pasta2CheckBox                 matlab.ui.control.CheckBox
        Pasta3CheckBox                 matlab.ui.control.CheckBox
        OutroCheckBox                  matlab.ui.control.CheckBox
        NovoDesenhoButton              matlab.ui.control.Button
        NCamadasDropDownLabel          matlab.ui.control.Label
        NCamadasDropDown               matlab.ui.control.DropDown
        FTreinoDropDownLabel           matlab.ui.control.Label
        FTreinoDropDown                matlab.ui.control.DropDown
        FSaidaDropDownLabel            matlab.ui.control.Label
        FSaidaDropDown                 matlab.ui.control.DropDown
        TopologiaDropDownLabel         matlab.ui.control.Label
        TopologiaDropDown              matlab.ui.control.DropDown
        NeurniosSpinnerLabel           matlab.ui.control.Label
        NeurniosSpinner                matlab.ui.control.Spinner
        pocasSpinnerLabel              matlab.ui.control.Label
        pocasSpinner                   matlab.ui.control.Spinner
        FuncoesAtivacaoPanel           matlab.ui.container.Panel
        FAtivacaocamada1DropDownLabel  matlab.ui.control.Label
        FAtivacaocamada1DropDown       matlab.ui.control.DropDown
        FAtivacaocamada2DropDownLabel  matlab.ui.control.Label
        FAtivacaocamada2DropDown       matlab.ui.control.DropDown
        FAtivacaocamada4DropDownLabel  matlab.ui.control.Label
        FAtivacaocamada4DropDown       matlab.ui.control.DropDown
        FAtivacaocamada3DropDownLabel  matlab.ui.control.Label
        FAtivacaocamada3DropDown       matlab.ui.control.DropDown
        FAtivacaocamada5DropDownLabel  matlab.ui.control.Label
        FAtivacaocamada5DropDown       matlab.ui.control.DropDown
        AlineaDropDownLabel            matlab.ui.control.Label
        AlineaDropDown                 matlab.ui.control.DropDown
        TextArea                       matlab.ui.control.TextArea
        ResultadosButton               matlab.ui.control.Button
    end

    
    properties (Access = public)
        Property1 % Alinea
        Property2 % Camada
        Property3 % Ftreino
        Property4 % Fsaida
        Property5 % Topologia
        Property6 % Neuronios 
        Property7 % Epocas
        Property8 % Treino
        Property9 % Teste
        Property10 % Validacao
        Property11 % Fativacao
        Property12 % Imagens
        Property13 % net
        Property14 % accuracytreino
        Property15 % accuracyteste
        Property16 % resultadoD
        
    end
    
    methods (Access = public)
        
        function results = func(app)
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AlineaDropDown
        function AlineaDropDownValueChanged(app, event)
            value = app.AlineaDropDown.Value;
            app.Property1 = value;
            app.TreinarRNButton.Enable = 'off';
            app.GuardarRNButton.Enable = 'off';
             app.ResultadosButton.Enable = 'off';
            if strcmp(app.Property1,"A") ~= 1
                app.DivisoparaTreinoTesteValidaoPanel.Enable = 'on';
                app.FuncoesAtivacaoPanel.Enable = 'on';
            elseif strcmp(app.Property1,"A") == 1
                app.DivisoparaTreinoTesteValidaoPanel.Enable = 'off';
                app.NovoDesenhoButton.Enable = 'off';
                app.Pasta1CheckBox.Enable = 'off';
                app.Pasta2CheckBox.Enable = 'off';
                app.Pasta3CheckBox.Enable = 'off';
                app.OutroCheckBox.Enable = 'off';
                
                app.NCamadasDropDown.Enable = 'on';
                app.NCamadasDropDownLabel.Enable = 'on';
                app.FuncoesAtivacaoPanel.Enable = 'on';
                
                app.Pasta1CheckBox.Value = true;
                app.Pasta2CheckBox.Value = false;
                app.Pasta3CheckBox.Value = false;
                app.OutroCheckBox.Value = false;
            end
            if strcmp(app.Property1,"B")== 1
                app.Pasta2CheckBox.Enable = 'on';
                app.Pasta1CheckBox.Enable = 'off';
                app.Pasta3CheckBox.Enable = 'off';
                app.OutroCheckBox.Enable = 'off';
                app.NCamadasDropDown.Enable = 'on';
                app.NCamadasDropDownLabel.Enable = 'on';
                app.Pasta1CheckBox.Value = false;              
                app.Pasta2CheckBox.Value = true;
                app.Pasta3CheckBox.Value = false;
                app.OutroCheckBox.Value = false;
            end
            if strcmp(app.Property1,"C")== 1
                app.Pasta1CheckBox.Enable = 'on';
                app.Pasta2CheckBox.Enable = 'on';
                app.Pasta3CheckBox.Enable = 'on';
                app.OutroCheckBox.Enable = 'off'; 
                app.Pasta1CheckBox.Value = false;
                app.Pasta2CheckBox.Value = false;
                app.Pasta3CheckBox.Value = false;
                app.OutroCheckBox.Value = false;
                            
            end        
            if strcmp(app.Property1,"D") == 1
                app.NovoDesenhoButton.Enable = 'on';
                app.Pasta1CheckBox.Enable = 'off';
                app.Pasta2CheckBox.Enable = 'off';
                app.Pasta3CheckBox.Enable = 'off';
                app.OutroCheckBox.Enable = 'on'; 
                app.ResultadosButton.Enable = 'on';
            end
            
            if (strcmp(app.Property1,"D") == 1 || strcmp(app.Property1,"C") == 1)
                clear app.Property13;
                app.AplicarRNButton.Enable = 'off';
                app.NCamadasDropDown.Enable = 'off';
                app.FTreinoDropDown.Enable = 'off';
                app.FSaidaDropDown.Enable = 'off';
                app.TopologiaDropDown.Enable = 'off';
                app.DivisoparaTreinoTesteValidaoPanel.Enable = 'off';
                app.FuncoesAtivacaoPanel.Enable = 'off';
                app.NeurniosSpinner.Enable = 'off';
                app.pocasSpinner.Enable = 'off';
            else
                app.AplicarRNButton.Enable = 'on';
                app.NCamadasDropDown.Enable = 'on';
                app.FTreinoDropDown.Enable = 'on';
                app.FSaidaDropDown.Enable = 'on';
                app.TopologiaDropDown.Enable = 'on';
                app.DivisoparaTreinoTesteValidaoPanel.Enable = 'on';
                app.FuncoesAtivacaoPanel.Enable = 'on';
                app.NeurniosSpinner.Enable = 'on';
                app.pocasSpinner.Enable = 'on';
            end
        end

        % Value changed function: NCamadasDropDown
        function NCamadasDropDownValueChanged(app, event)
            app.TreinarRNButton.Enable = 'off';
            app.GuardarRNButton.Enable = 'off';
            camada = str2double(app.NCamadasDropDown.Value);
            if camada == 1 && strcmp(app.NCamadasDropDown.Enable,'on')==1
                app.FAtivacaocamada1DropDown.Enable = 'on';
                app.FAtivacaocamada1DropDownLabel.Enable = 'on';
                app.FAtivacaocamada2DropDown.Enable = 'off';
                app.FAtivacaocamada2DropDownLabel.Enable = 'off';
                app.FAtivacaocamada3DropDown.Enable = 'off';
                app.FAtivacaocamada3DropDownLabel.Enable = 'off';
                app.FAtivacaocamada4DropDown.Enable = 'off';
                app.FAtivacaocamada4DropDownLabel.Enable = 'off';
                app.FAtivacaocamada5DropDown.Enable = 'off';
                app.FAtivacaocamada5DropDownLabel.Enable = 'off';
            end
            if camada == 2 && strcmp(app.NCamadasDropDown.Enable,'on')==1
                app.FAtivacaocamada1DropDown.Enable = 'on';
                app.FAtivacaocamada1DropDownLabel.Enable = 'on';
                app.FAtivacaocamada2DropDown.Enable = 'on';
                app.FAtivacaocamada2DropDownLabel.Enable = 'on';
                app.FAtivacaocamada3DropDown.Enable = 'off';
                app.FAtivacaocamada3DropDownLabel.Enable = 'off';
                app.FAtivacaocamada4DropDown.Enable = 'off';
                app.FAtivacaocamada4DropDownLabel.Enable = 'off';
                app.FAtivacaocamada5DropDown.Enable = 'off';
                app.FAtivacaocamada5DropDownLabel.Enable = 'off';
            end
            if camada == 3 && strcmp(app.NCamadasDropDown.Enable,'on')==1
                app.FAtivacaocamada1DropDown.Enable = 'on';
                app.FAtivacaocamada1DropDownLabel.Enable = 'on';
                app.FAtivacaocamada2DropDown.Enable = 'on';
                app.FAtivacaocamada2DropDownLabel.Enable = 'on';
                app.FAtivacaocamada3DropDown.Enable = 'on';
                app.FAtivacaocamada3DropDownLabel.Enable = 'on';
                app.FAtivacaocamada4DropDown.Enable = 'off';
                app.FAtivacaocamada4DropDownLabel.Enable = 'off';
                app.FAtivacaocamada5DropDown.Enable = 'off';
                app.FAtivacaocamada5DropDownLabel.Enable = 'off';
            end
            if camada == 4 && strcmp(app.NCamadasDropDown.Enable,'on')==1
                app.FAtivacaocamada1DropDown.Enable = 'on';
                app.FAtivacaocamada1DropDownLabel.Enable = 'on';
                app.FAtivacaocamada2DropDown.Enable = 'on';
                app.FAtivacaocamada2DropDownLabel.Enable = 'on';
                app.FAtivacaocamada3DropDown.Enable = 'on';
                app.FAtivacaocamada3DropDownLabel.Enable = 'on';
                app.FAtivacaocamada4DropDown.Enable = 'on';
                app.FAtivacaocamada4DropDownLabel.Enable = 'on';
                app.FAtivacaocamada5DropDown.Enable = 'off';
                app.FAtivacaocamada5DropDownLabel.Enable = 'off';
            end
            if camada == 5 && strcmp(app.NCamadasDropDown.Enable,'on')==1
                app.FAtivacaocamada1DropDown.Enable = 'on';
                app.FAtivacaocamada1DropDownLabel.Enable = 'on';
                app.FAtivacaocamada2DropDown.Enable = 'on';
                app.FAtivacaocamada2DropDownLabel.Enable = 'on';
                app.FAtivacaocamada3DropDown.Enable = 'on';
                app.FAtivacaocamada3DropDownLabel.Enable = 'on';
                app.FAtivacaocamada4DropDown.Enable = 'on';
                app.FAtivacaocamada4DropDownLabel.Enable = 'on';
                app.FAtivacaocamada5DropDown.Enable = 'on';
                app.FAtivacaocamada5DropDownLabel.Enable = 'on';
            end           
        end

        % Button pushed function: TreinarRNButton
        function TreinarRNButtonPushed(app, event)
            Pasta1 = app.Pasta1CheckBox.Value;
            Pasta2 = app.Pasta2CheckBox.Value;
            Pasta3 = app.Pasta3CheckBox.Value;
            Outro = app.OutroCheckBox.Value;
            
            if Pasta1 == true
                app.Property12(1) = 1;
            else
                app.Property12(1) = 0;
            end
            if Pasta2 == true
                app.Property12(2) = 1;
            else
                app.Property12(2) = 0;
            end
            if Pasta3 == true
                app.Property12(3) = 1;
            else
                app.Property12(3) = 0;
            end
            if Outro == true
                app.Property12(4) = 1;
            else
                app.Property12(4) = 0;
            end
            
            value = app.Property1;
            
            camada = app.Property2;
            
            ftreino = app.Property3;
            
            neuronios = app.Property6;
            
            topologia = app.Property5;
            
            epocas = app.Property7;
            
            treino = app.Property8;
            
            teste = app.Property9;
            
            validacao = app.Property10;
            
            fativacao = app.Property11;
            
            if strcmp(value,"A") == 1
                [app.Property13,app.Property14] = functionA(topologia,neuronios,ftreino,epocas,camada,fativacao);
            end
            if strcmp(value,"B") == 1
                [app.Property13, app.Property14, app.Property15] = functionB(topologia,neuronios,ftreino,epocas,camada,treino,teste,validacao,fativacao);
            end
            if strcmp(value,"C") == 1         
                if (app.Property12(1)==0 && app.Property12(2)==0 && app.Property12(3)==1 && app.Property12(4)==0)
                     [app.Property13, app.Property14, app.Property15] = functionCtrain(app.Property13);
                elseif ((app.Property12(1)==1 || app.Property12(2)==1) && app.Property12(3)==1 && app.Property12(4)==0)
                    [app.Property13, app.Property14, app.Property15] = functionCtodos(app.Property13);
                end
            end
            app.GuardarRNButton.Enable = 'on';
        end

        % Button pushed function: AplicarRNButton
        function AplicarRNButtonPushed(app, event)
            app.Property6 = 0;
            app.Property11 = ' ';
            
            app.GuardarRNButton.Enable = 'off';
            value = app.AlineaDropDown.Value;
            app.Property1 = value;
            
            camada = str2double(app.NCamadasDropDown.Value);
            app.Property2 = camada;
           
            ftreino = app.FTreinoDropDown.Value;
            app.Property3 = ftreino;
            
            fsaida = app.FSaidaDropDown.Value;
            app.Property4 = fsaida;
            
            topologia = app.TopologiaDropDown.Value;
            app.Property5 = topologia;
            neuronios = app.NeurniosSpinner.Value;
            for i = 1:camada
                app.Property6(i) = neuronios;
            end
            
            if camada == 5
                fativacao = {app.FAtivacaocamada1DropDown.Value, app.FAtivacaocamada2DropDown.Value, app.FAtivacaocamada3DropDown.Value, app.FAtivacaocamada4DropDown.Value, app.FAtivacaocamada5DropDown.Value, fsaida};
                app.Property11 = fativacao;
            elseif camada == 4
                fativacao = {app.FAtivacaocamada1DropDown.Value, app.FAtivacaocamada2DropDown.Value, app.FAtivacaocamada3DropDown.Value, app.FAtivacaocamada4DropDown.Value, fsaida};
                app.Property11 = fativacao;
            elseif camada == 3
                fativacao = {app.FAtivacaocamada1DropDown.Value, app.FAtivacaocamada2DropDown.Value, app.FAtivacaocamada3DropDown.Value,fsaida};
                app.Property11 = fativacao;
            elseif camada == 2
                fativacao = {app.FAtivacaocamada1DropDown.Value, app.FAtivacaocamada2DropDown.Value,fsaida};
                app.Property11 = fativacao;
            elseif camada == 1
                fativacao = {app.FAtivacaocamada1DropDown.Value,fsaida};
                app.Property11 = fativacao;
            end
            
            epocas = app.pocasSpinner.Value;
            app.Property7 = epocas;
            
            if strcmp(app.DivisoparaTreinoTesteValidaoPanel.Enable,'on') == 1
                treino = app.TreinoSpinner.Value;
                app.Property8 = treino;
           
                teste = app.TesteSpinner.Value;
                app.Property9 = teste;
            
                validacao = app.ValidacaoSpinner.Value;
                app.Property10 = validacao;
            else
                app.Property10 = 0;
                app.Property9 = 0;
                app.Property8 = 0;
            end
            
            app.TreinarRNButton.Enable = 'on';
        end

        % Button pushed function: GuardarRNButton
        function GuardarRNButtonPushed(app, event)
            net=app.Property13;
            name = inputdlg('Introduza o nome da rede:', 'Gravar rede');
            
            fname = sprintf('%s.mat', name{1});
            
            save (fname,'net')
        end

        % Button pushed function: CarregarRNButton
        function CarregarRNButtonPushed(app, event)
            name = inputdlg('Introduza o nome da rede a usar:', 'Load da rede');
            clear app.Property13;
            fname = sprintf('%s.mat', name{1});
            app.Property13=fname;
        end

        % Value changed function: FTreinoDropDown
        function FTreinoDropDownValueChanged(app, event)
            app.TreinarRNButton.Enable = 'off';
            app.GuardarRNButton.Enable = 'off';
        end

        % Value changed function: FSaidaDropDown
        function FSaidaDropDownValueChanged(app, event)
            app.TreinarRNButton.Enable = 'off';
            app.GuardarRNButton.Enable = 'off';            
        end

        % Value changed function: TopologiaDropDown
        function TopologiaDropDownValueChanged(app, event)
            app.TreinarRNButton.Enable = 'off';
            app.GuardarRNButton.Enable = 'off';            
        end

        % Value changed function: NeurniosSpinner
        function NeurniosSpinnerValueChanged(app, event)
            app.TreinarRNButton.Enable = 'off';
            app.GuardarRNButton.Enable = 'off';
        end

        % Value changed function: pocasSpinner
        function pocasSpinnerValueChanged(app, event)
            app.TreinarRNButton.Enable = 'off';
            app.GuardarRNButton.Enable = 'off';           
        end

        % Value changed function: TreinoSpinner
        function TreinoSpinnerValueChanged(app, event)
            app.TreinarRNButton.Enable = 'off';
            app.GuardarRNButton.Enable = 'off';          
        end

        % Value changed function: ValidacaoSpinner
        function ValidacaoSpinnerValueChanged(app, event)
            app.TreinarRNButton.Enable = 'off';
            app.GuardarRNButton.Enable = 'off';           
        end

        % Value changed function: TesteSpinner
        function TesteSpinnerValueChanged(app, event)
            app.TreinarRNButton.Enable = 'off';
            app.GuardarRNButton.Enable = 'off';            
        end

        % Value changed function: FAtivacaocamada1DropDown
        function FAtivacaocamada1DropDownValueChanged(app, event)
            app.TreinarRNButton.Enable = 'off';
            app.GuardarRNButton.Enable = 'off';
        end

        % Value changed function: FAtivacaocamada2DropDown
        function FAtivacaocamada2DropDownValueChanged(app, event)
            app.TreinarRNButton.Enable = 'off';
            app.GuardarRNButton.Enable = 'off';
        end

        % Value changed function: FAtivacaocamada3DropDown
        function FAtivacaocamada3DropDownValueChanged(app, event)
            app.TreinarRNButton.Enable = 'off';
            app.GuardarRNButton.Enable = 'off';
        end

        % Value changed function: FAtivacaocamada4DropDown
        function FAtivacaocamada4DropDownValueChanged(app, event)
            app.TreinarRNButton.Enable = 'off';
            app.GuardarRNButton.Enable = 'off';
        end

        % Value changed function: FAtivacaocamada5DropDown
        function FAtivacaocamada5DropDownValueChanged(app, event)
            app.TreinarRNButton.Enable = 'off';
            app.GuardarRNButton.Enable = 'off';
        end

        % Button pushed function: NovoDesenhoButton
        function NovoDesenhoButtonPushed(app, event)
            system('mspaint');
        end

        % Button pushed function: ResultadosButton
        function ResultadosButtonPushed(app, event)
            Pasta1 = app.Pasta1CheckBox.Value;
            Pasta2 = app.Pasta2CheckBox.Value;
            Pasta3 = app.Pasta3CheckBox.Value;
            Outro = app.OutroCheckBox.Value;
            
            if Pasta1 == true
                app.Property12(1) = 1;
            else
                app.Property12(1) = 0;
            end
            if Pasta2 == true
                app.Property12(2) = 1;
            else
                app.Property12(2) = 0;
            end
            if Pasta3 == true
                app.Property12(3) = 1;
            else
                app.Property12(3) = 0;
            end
            if Outro == true
                app.Property12(4) = 1;
            else
                app.Property12(4) = 0;
            end
            
            value = app.AlineaDropDown.Value;
            app.Property1 = value;
            
            if strcmp(value,"C") == 1         
                if (app.Property12(1)==0 && app.Property12(2)==0 && app.Property12(3)==1 && app.Property12(4)==0)
                    functionC(app.Property13);
                elseif (app.Property12(1)==1 && app.Property12(2)==0 && app.Property12(3)==0 && app.Property12(4)==0)
                    functionCstart(app.Property13);
                elseif (app.Property12(1)==0 && app.Property12(2)==1 && app.Property12(3)==0 && app.Property12(4)==0)
                    functionCtest(app.Property13);
                end                
            end
            if strcmp(value,"D") == 1  
                name = inputdlg('Introduza o nome da imagem a classificar:', 'Load da imagem',[1 35]);
                fname = sprintf('%s.png', name{1});
                [app.Property16] = functionD(fname,app.Property13);
                app.TextArea.Value=app.Property16;
            end
        end

        % Value changed function: Pasta1CheckBox
        function Pasta1CheckBoxValueChanged(app, event)
            value = app.AlineaDropDown.Value;
            app.Property1 = value;
            if strcmp(value,"C") == 1    
                if app.Pasta1CheckBox.Value == true
                    app.ResultadosButton.Enable = 'on';
                else
                     app.ResultadosButton.Enable = 'off';
                end
                if app.Pasta1CheckBox.Value == true && app.Pasta2CheckBox.Value == true && app.Pasta3CheckBox.Value == true
                    app.TreinarRNButton.Enable = 'on';
                end
            end
        end

        % Value changed function: Pasta2CheckBox
        function Pasta2CheckBoxValueChanged(app, event)
            value = app.AlineaDropDown.Value;
            app.Property1 = value;
           if strcmp(value,"C") == 1
                if app.Pasta2CheckBox.Value == true
                    app.ResultadosButton.Enable = 'on';
                else
                     app.ResultadosButton.Enable = 'off';
                end
                if app.Pasta1CheckBox.Value == true && app.Pasta2CheckBox.Value == true && app.Pasta3CheckBox.Value == true
                    app.TreinarRNButton.Enable = 'on';
                end
           end
        end

        % Value changed function: Pasta3CheckBox
        function Pasta3CheckBoxValueChanged(app, event)
            value = app.AlineaDropDown.Value;
            app.Property1 = value;
            if strcmp(value,"C") == 1
                if app.Pasta3CheckBox.Value == true
                    app.ResultadosButton.Enable = 'on';
                    app.TreinarRNButton.Enable = 'on';
                else
                    app.ResultadosButton.Enable = 'off';
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConfiguracaoRedePanel = uipanel(app.UIFigure);
            app.ConfiguracaoRedePanel.Title = 'Configuracao Rede';
            app.GuardarRNButton = uibutton(app.ConfiguracaoRedePanel, 'push');
            app.GuardarRNButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarRNButtonPushed, true);
            app.GuardarRNButton.Text = 'Guardar RN';
            app.CarregarRNButton = uibutton(app.ConfiguracaoRedePanel, 'push');
            app.CarregarRNButton.ButtonPushedFcn = createCallbackFcn(app, @CarregarRNButtonPushed, true);
            app.CarregarRNButton.Text = 'Carregar RN';
            app.TreinarRNButton = uibutton(app.ConfiguracaoRedePanel, 'push');
            app.TreinarRNButton.ButtonPushedFcn = createCallbackFcn(app, @TreinarRNButtonPushed, true);
            app.TreinarRNButton.Text = 'Treinar RN';
            app.AplicarRNButton = uibutton(app.ConfiguracaoRedePanel, 'push');
            app.AplicarRNButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarRNButtonPushed, true);
            app.AplicarRNButton.Text = 'Aplicar RN';
            app.DivisoparaTreinoTesteValidaoPanel = uipanel(app.ConfiguracaoRedePanel);
            app.DivisoparaTreinoTesteValidaoPanel.Title = 'Divisão para Treino, Teste, Validação';
            app.TesteSpinnerLabel = uilabel(app.DivisoparaTreinoTesteValidaoPanel);
            app.TesteSpinnerLabel.Text = 'Teste';
            app.TesteSpinner = uispinner(app.DivisoparaTreinoTesteValidaoPanel);
            app.TesteSpinner.ValueChangedFcn = createCallbackFcn(app, @TesteSpinnerValueChanged, true);
            app.TesteSpinner.Value = 15;
            app.ValidacaoSpinnerLabel = uilabel(app.DivisoparaTreinoTesteValidaoPanel);
            app.ValidacaoSpinnerLabel.Text = 'Validacao';
            app.ValidacaoSpinner = uispinner(app.DivisoparaTreinoTesteValidaoPanel);
            app.ValidacaoSpinner.ValueChangedFcn = createCallbackFcn(app, @ValidacaoSpinnerValueChanged, true);
            app.ValidacaoSpinner.Value = 15;
            app.TreinoSpinnerLabel = uilabel(app.DivisoparaTreinoTesteValidaoPanel);
            app.TreinoSpinnerLabel.Text = 'Treino';
            app.TreinoSpinner = uispinner(app.DivisoparaTreinoTesteValidaoPanel);
            app.TreinoSpinner.ValueChangedFcn = createCallbackFcn(app, @TreinoSpinnerValueChanged, true);
            app.TreinoSpinner.Value = 70;
            app.ImagensPanel = uipanel(app.ConfiguracaoRedePanel);
            app.ImagensPanel.Title = 'Imagens';
            app.Pasta1CheckBox = uicheckbox(app.ImagensPanel);
            app.Pasta1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Pasta1CheckBoxValueChanged, true);
            app.Pasta1CheckBox.Text = 'Pasta 1';
            app.Pasta2CheckBox = uicheckbox(app.ImagensPanel);
            app.Pasta2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Pasta2CheckBoxValueChanged, true);
            app.Pasta2CheckBox.Text = 'Pasta 2';
            app.Pasta3CheckBox = uicheckbox(app.ImagensPanel);
            app.Pasta3CheckBox.ValueChangedFcn = createCallbackFcn(app, @Pasta3CheckBoxValueChanged, true);
            app.Pasta3CheckBox.Text = 'Pasta 3';
            app.OutroCheckBox = uicheckbox(app.ImagensPanel);
            app.OutroCheckBox.Text = 'Outro';
            app.NovoDesenhoButton = uibutton(app.ConfiguracaoRedePanel, 'push');
            app.NovoDesenhoButton.ButtonPushedFcn = createCallbackFcn(app, @NovoDesenhoButtonPushed, true);
            app.NovoDesenhoButton.Text = 'Novo Desenho';
            app.NCamadasDropDownLabel = uilabel(app.ConfiguracaoRedePanel);
            app.NCamadasDropDownLabel.Text = 'N Camadas';
            app.NCamadasDropDown = uidropdown(app.ConfiguracaoRedePanel);
            app.NCamadasDropDown.Items = {'1', '2', '3', '4', '5'};
            app.NCamadasDropDown.ValueChangedFcn = createCallbackFcn(app, @NCamadasDropDownValueChanged, true);
            app.NCamadasDropDown.Value = '1';
            app.FTreinoDropDownLabel = uilabel(app.ConfiguracaoRedePanel);
            app.FTreinoDropDownLabel.Text = 'F.Treino';
            app.FTreinoDropDown = uidropdown(app.ConfiguracaoRedePanel);
            app.FTreinoDropDown.Items = {'trainlm', 'trainbfg', 'traingd'};
            app.FTreinoDropDown.ValueChangedFcn = createCallbackFcn(app, @FTreinoDropDownValueChanged, true);
            app.FTreinoDropDown.Value = 'trainlm';
            app.FSaidaDropDownLabel = uilabel(app.ConfiguracaoRedePanel);
            app.FSaidaDropDownLabel.Text = 'F.Saida';
            app.FSaidaDropDown = uidropdown(app.ConfiguracaoRedePanel);
            app.FSaidaDropDown.Items = {'purelin', 'tansig', 'logsig'};
            app.FSaidaDropDown.ValueChangedFcn = createCallbackFcn(app, @FSaidaDropDownValueChanged, true);
            app.FSaidaDropDown.Value = 'purelin';
            app.TopologiaDropDownLabel = uilabel(app.ConfiguracaoRedePanel);
            app.TopologiaDropDownLabel.Text = 'Topologia';
            app.TopologiaDropDown = uidropdown(app.ConfiguracaoRedePanel);
            app.TopologiaDropDown.Items = {'feedforwardnet', 'patternet', 'perceptron'};
            app.TopologiaDropDown.ValueChangedFcn = createCallbackFcn(app, @TopologiaDropDownValueChanged, true);
            app.TopologiaDropDown.Value = 'feedforwardnet';
            app.NeurniosSpinnerLabel = uilabel(app.ConfiguracaoRedePanel);
            app.NeurniosSpinnerLabel.Text = 'Neurónios:';
            app.NeurniosSpinner = uispinner(app.ConfiguracaoRedePanel);
            app.NeurniosSpinner.ValueChangedFcn = createCallbackFcn(app, @NeurniosSpinnerValueChanged, true);
            app.NeurniosSpinner.Value = 10;
            app.pocasSpinnerLabel = uilabel(app.ConfiguracaoRedePanel);
            app.pocasSpinnerLabel.Text = 'Épocas';
            app.pocasSpinner = uispinner(app.ConfiguracaoRedePanel);
            app.pocasSpinner.ValueChangedFcn = createCallbackFcn(app, @pocasSpinnerValueChanged, true);
            app.pocasSpinner.Value = 100;
            app.FuncoesAtivacaoPanel = uipanel(app.ConfiguracaoRedePanel);
            app.FuncoesAtivacaoPanel.Title = 'Funcoes Ativacao';
            app.FAtivacaocamada1DropDownLabel = uilabel(app.FuncoesAtivacaoPanel);
            app.FAtivacaocamada1DropDownLabel.Text = 'F. Ativacao camada 1';
            app.FAtivacaocamada1DropDown = uidropdown(app.FuncoesAtivacaoPanel);
            app.FAtivacaocamada1DropDown.Items = {'tansig', 'purelin', 'logsig'};
            app.FAtivacaocamada1DropDown.ValueChangedFcn = createCallbackFcn(app, @FAtivacaocamada1DropDownValueChanged, true);
            app.FAtivacaocamada1DropDown.Value = 'tansig';
            app.FAtivacaocamada2DropDownLabel = uilabel(app.FuncoesAtivacaoPanel);
            app.FAtivacaocamada2DropDownLabel.Text = 'F. Ativacao camada 2';
            app.FAtivacaocamada2DropDown = uidropdown(app.FuncoesAtivacaoPanel);
            app.FAtivacaocamada2DropDown.Items = {'tansig', 'purelin', 'logsig'};
            app.FAtivacaocamada2DropDown.ValueChangedFcn = createCallbackFcn(app, @FAtivacaocamada2DropDownValueChanged, true);
            app.FAtivacaocamada2DropDown.Value = 'tansig';
            app.FAtivacaocamada4DropDownLabel = uilabel(app.FuncoesAtivacaoPanel);
            app.FAtivacaocamada4DropDownLabel.Text = 'F. Ativacao camada 4';
            app.FAtivacaocamada4DropDown = uidropdown(app.FuncoesAtivacaoPanel);
            app.FAtivacaocamada4DropDown.Items = {'tansig', 'purelin', 'logsig'};
            app.FAtivacaocamada4DropDown.ValueChangedFcn = createCallbackFcn(app, @FAtivacaocamada4DropDownValueChanged, true);
            app.FAtivacaocamada4DropDown.Value = 'tansig';
            app.FAtivacaocamada3DropDownLabel = uilabel(app.FuncoesAtivacaoPanel);
            app.FAtivacaocamada3DropDownLabel.Text = 'F. Ativacao camada 3';
            app.FAtivacaocamada3DropDown = uidropdown(app.FuncoesAtivacaoPanel);
            app.FAtivacaocamada3DropDown.Items = {'tansig', 'purelin', 'logsig'};
            app.FAtivacaocamada3DropDown.ValueChangedFcn = createCallbackFcn(app, @FAtivacaocamada3DropDownValueChanged, true);
            app.FAtivacaocamada3DropDown.Value = 'tansig';
            app.FAtivacaocamada5DropDownLabel = uilabel(app.FuncoesAtivacaoPanel);
            app.FAtivacaocamada5DropDownLabel.Text = 'F. Ativacao camada 5';
            app.FAtivacaocamada5DropDown = uidropdown(app.FuncoesAtivacaoPanel);
            app.FAtivacaocamada5DropDown.Items = {'tansig', 'purelin', 'logsig'};
            app.FAtivacaocamada5DropDown.ValueChangedFcn = createCallbackFcn(app, @FAtivacaocamada5DropDownValueChanged, true);
            app.FAtivacaocamada5DropDown.Value = 'tansig';
            app.AlineaDropDownLabel = uilabel(app.ConfiguracaoRedePanel);
            app.AlineaDropDownLabel.Text = 'Alinea';
            app.AlineaDropDown = uidropdown(app.ConfiguracaoRedePanel);
            app.AlineaDropDown.Items = {'A', 'B', 'C', 'D'};
            app.AlineaDropDown.ValueChangedFcn = createCallbackFcn(app, @AlineaDropDownValueChanged, true);
            app.AlineaDropDown.Value = 'A';
            app.TextArea = uitextarea(app.ConfiguracaoRedePanel);
            app.ResultadosButton = uibutton(app.ConfiguracaoRedePanel, 'push');
            app.ResultadosButton.ButtonPushedFcn = createCallbackFcn(app, @ResultadosButtonPushed, true);
            app.ResultadosButton.Text = 'Resultados';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_RN

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end